classdef AFS_GUI_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StatusupdateLabel               matlab.ui.control.Label
        field_general_status_update     matlab.ui.control.TextArea
        SystempropertiesPanel           matlab.ui.container.Panel
        BeaddiameterumLabel             matlab.ui.control.Label
        field_bead_diameter             matlab.ui.control.NumericEditField
        MagnificationDropDown           matlab.ui.control.DropDown
        MagnificationDropDownLabel      matlab.ui.control.Label
        ExporttrackingdataMtoworkspaceButton  matlab.ui.control.Button
        RemovebeadsButton               matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        AnchorpointanddriftcorrectionTab  matlab.ui.container.Tab
        DriftcorrectionPanel            matlab.ui.container.Panel
        field_averaging_window_drift_correction  matlab.ui.control.NumericEditField
        AveragingwindowLabel            matlab.ui.control.Label
        DisplayaveragedtraceButton      matlab.ui.control.Button
        BeadsusedfordriftcorrectionLabel  matlab.ui.control.Label
        ChosenBeadsTextArea             matlab.ui.control.TextArea
        StuckBeadsTextArea              matlab.ui.control.TextArea
        BeadssuggestedfordriftcorrectionLabel  matlab.ui.control.Label
        CorrectfordriftinzButton        matlab.ui.control.Button
        FindstuckbeadsButton            matlab.ui.control.Button
        field_stuck_beads_cutoff        matlab.ui.control.NumericEditField
        CutoffinznmLabel                matlab.ui.control.Label
        AnchorpointPanel                matlab.ui.container.Panel
        DetermineanchorpointButton      matlab.ui.control.Button
        field_anchorpoint_end           matlab.ui.control.NumericEditField
        AnchorpointendsLabel            matlab.ui.control.Label
        field_anchorpoint_start         matlab.ui.control.NumericEditField
        AnchorpointstartsLabel          matlab.ui.control.Label
        plot_z_drift                    matlab.ui.control.UIAxes
        plot_y_drift                    matlab.ui.control.UIAxes
        plot_x_drift                    matlab.ui.control.UIAxes
        FiltertracesTab                 matlab.ui.container.Tab
        ForindividualFCfitdataonlyPanel  matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        RemoveforcefitoutliersButton    matlab.ui.control.Button
        PlotforcefithistogramsButton    matlab.ui.control.Button
        RemoveRMSandStdDevzoutliersButton  matlab.ui.control.Button
        znodeuppercutumEditField        matlab.ui.control.NumericEditField
        znodeuppercutumEditFieldLabel   matlab.ui.control.Label
        RsquppercutEditField            matlab.ui.control.NumericEditField
        RsquppercutEditFieldLabel       matlab.ui.control.Label
        PlotRMSandStdDevzhistogramsButton  matlab.ui.control.Button
        znodelowercutumEditField        matlab.ui.control.NumericEditField
        znodelowercutumEditFieldLabel   matlab.ui.control.Label
        RsqlowercutEditField            matlab.ui.control.NumericEditField
        RsqlowercutEditFieldLabel       matlab.ui.control.Label
        StdDevzcutoffnmEditField        matlab.ui.control.NumericEditField
        StdDevzcutoffnmEditFieldLabel   matlab.ui.control.Label
        phipuppercutEditField           matlab.ui.control.NumericEditField
        phipuppercutEditFieldLabel      matlab.ui.control.Label
        kpuppercut1umEditField          matlab.ui.control.NumericEditField
        kpuppercut1umEditFieldLabel     matlab.ui.control.Label
        fouppercutpNumEditField         matlab.ui.control.NumericEditField
        fouppercutpNumEditFieldLabel    matlab.ui.control.Label
        RMSuppercutnmEditField          matlab.ui.control.NumericEditField
        RMSuppercutnmEditFieldLabel     matlab.ui.control.Label
        phiplowercutEditField           matlab.ui.control.NumericEditField
        phiplowercutEditFieldLabel      matlab.ui.control.Label
        kplowercut1umEditField          matlab.ui.control.NumericEditField
        kplowercut1umEditFieldLabel     matlab.ui.control.Label
        folowercutpNumEditField         matlab.ui.control.NumericEditField
        folowercutpNumEditFieldLabel    matlab.ui.control.Label
        RMSlowercutnmEditField          matlab.ui.control.NumericEditField
        RMSlowercutnmEditFieldLabel     matlab.ui.control.Label
        zNodeHistogram                  matlab.ui.control.UIAxes
        plot_Stdev_z_histogram          matlab.ui.control.UIAxes
        plot_Rsq_histogram              matlab.ui.control.UIAxes
        plot_phip_histogram             matlab.ui.control.UIAxes
        plot_kp_histogram               matlab.ui.control.UIAxes
        plot_fo_histogram               matlab.ui.control.UIAxes
        plot_RMS_histogram              matlab.ui.control.UIAxes
        ForcecalibrationTab             matlab.ui.container.Tab
        FCBeadsTextArea                 matlab.ui.control.TextArea
        BeadsnotsuitableforforcecalibrationLabel  matlab.ui.control.Label
        Bead_fit_parameter_table        matlab.ui.control.Table
        ForcefitinspectionPanel         matlab.ui.container.Panel
        CollectbeadsforheatmapButton    matlab.ui.control.Button
        z_endumEditField                matlab.ui.control.NumericEditField
        zrangeumLabel                   matlab.ui.control.Label
        PlotforceprofileButton          matlab.ui.control.Button
        PlotforcefitpertraceButton      matlab.ui.control.Button
        showbeadsCheckBox               matlab.ui.control.CheckBox
        VisualizeforcedistributioninFoVButton  matlab.ui.control.Button
        zmaxEditField                   matlab.ui.control.NumericEditField
        zminEditField                   matlab.ui.control.NumericEditField
        znoderangeumLabel               matlab.ui.control.Label
        FmaxEditField                   matlab.ui.control.NumericEditField
        FminEditField                   matlab.ui.control.NumericEditField
        ForcerangepNLabel               matlab.ui.control.Label
        upperlimitLabel                 matlab.ui.control.Label
        lowerlimitLabel                 matlab.ui.control.Label
        ForcefitsettingsPanel           matlab.ui.container.Panel
        averagingwindowEditField        matlab.ui.control.NumericEditField
        AveragingwindowforzLabel        matlab.ui.control.Label
        RminEditField                   matlab.ui.control.NumericEditField
        RminLabel                       matlab.ui.control.Label
        phipEditField_3                 matlab.ui.control.NumericEditField
        phipEditField_2                 matlab.ui.control.NumericEditField
        phipEditField                   matlab.ui.control.NumericEditField
        phipLabel                       matlab.ui.control.Label
        kpEditField_3                   matlab.ui.control.NumericEditField
        kpEditField_2                   matlab.ui.control.NumericEditField
        kpEditField                     matlab.ui.control.NumericEditField
        kp1umLabel                      matlab.ui.control.Label
        f0EditField_3                   matlab.ui.control.NumericEditField
        f0EditField_2                   matlab.ui.control.NumericEditField
        f0EditField                     matlab.ui.control.NumericEditField
        fopNumLabel                     matlab.ui.control.Label
        upperboundLabel                 matlab.ui.control.Label
        startingvalueLabel              matlab.ui.control.Label
        lowerbondLabel                  matlab.ui.control.Label
        ExecuteforcefitButton           matlab.ui.control.Button
        FindbeadsforforcecalibrationPanel  matlab.ui.container.Panel
        RemovebeadsbelowcutoffButton    matlab.ui.control.Button
        FindbeadsButton                 matlab.ui.control.Button
        FCt_maxsEditField               matlab.ui.control.NumericEditField
        ForcecalibrationendsLabel       matlab.ui.control.Label
        FCt_minsEditField               matlab.ui.control.NumericEditField
        ForcecalibrationstartsLabel     matlab.ui.control.Label
        zcutoffnmEditField              matlab.ui.control.NumericEditField
        zcutoffumLabel                  matlab.ui.control.Label
        plot_force_profile_per_trace    matlab.ui.control.UIAxes
        plot_force_fit_for_trace        matlab.ui.control.UIAxes
        plot_beads_for_FC               matlab.ui.control.UIAxes
        HeatmapgenerationTab            matlab.ui.container.Tab
        NstructcontentPanel             matlab.ui.container.Panel
        PowerLabel                      matlab.ui.control.Label
        PowerEditField                  matlab.ui.control.NumericEditField
        VisusalizebeadcollectionButton  matlab.ui.control.Button
        table_power_values              matlab.ui.control.Table
        ForcecalibrationfactorfitparamtersnormaldistributionTextArea  matlab.ui.control.TextArea
        LoadingratefitparametersTextArea_2Label  matlab.ui.control.Label
        HeatmapoperationsPanel          matlab.ui.container.Panel
        showbeadsCheckBox_heatmap       matlab.ui.control.CheckBox
        MeshmergingsizeumLabel          matlab.ui.control.Label
        zforforceestimationumLabel      matlab.ui.control.Label
        SamplepowerfitButton            matlab.ui.control.Button
        SaveHeatmapButton               matlab.ui.control.Button
        LoadHeatmapButton               matlab.ui.control.Button
        MeshmergingsizeumEditField      matlab.ui.control.NumericEditField
        zforforceestimationumEditField  matlab.ui.control.NumericEditField
        CreateheatmapButton             matlab.ui.control.Button
        NstructoperationsPanel          matlab.ui.container.Panel
        ClearNButton                    matlab.ui.control.Button
        ExportNtoworkspaceButton        matlab.ui.control.Button
        infiltertracestabLabel          matlab.ui.control.Label
        RemoveparameteroutliersButton   matlab.ui.control.Button
        PlotparameterhistogramsButton   matlab.ui.control.Button
        LoadNstructButton               matlab.ui.control.Button
        plot_full_heatmap               matlab.ui.control.UIAxes
        plot_FC_factors                 matlab.ui.control.UIAxes
        plot_bead_distribution          matlab.ui.control.UIAxes
        RuptureforcedeterminationTab    matlab.ui.container.Tab
        DataexportcsvPanel              matlab.ui.container.Panel
        ExportruptureforcedataButton    matlab.ui.control.Button
        field_RF_data_filepath_export   matlab.ui.control.TextArea
        FilepathLabel                   matlab.ui.control.Label
        FilenameLabel                   matlab.ui.control.Label
        field_RF_data_filename_export   matlab.ui.control.EditField
        RuptureforcehistogramsPanel     matlab.ui.container.Panel
        FitloadingrateandruptureforceButton  matlab.ui.control.Button
        ForcefitDropDown                matlab.ui.control.DropDown
        ForcefitDropDownLabel           matlab.ui.control.Label
        LoadingratefitDropDown          matlab.ui.control.DropDown
        LoadingratefitLabel             matlab.ui.control.Label
        PlotruptureforceandloadingratehistogramButton  matlab.ui.control.Button
        ManualruptureforcedeterminationPanel  matlab.ui.container.Panel
        TracesformanualruptureforcedeterminationLabel  matlab.ui.control.Label
        BeadsformanualRFcheckTextArea   matlab.ui.control.TextArea
        AssignrupturetimeButton         matlab.ui.control.Button
        RupturetimeinputsLabel          matlab.ui.control.Label
        SuggestedrupturetimesLabel      matlab.ui.control.Label
        field_rupture_time_input        matlab.ui.control.NumericEditField
        suggestedRupturetimeTextArea    matlab.ui.control.TextArea
        PlotrupturebeadtraceButton      matlab.ui.control.Button
        RuptureforcesettingsPanel       matlab.ui.container.Panel
        FindruptureforceButton          matlab.ui.control.Button
        CutoffforznmLabel               matlab.ui.control.Label
        AveragingwindowforzLabel_2      matlab.ui.control.Label
        RuptureforceendsLabel           matlab.ui.control.Label
        RuptureforcestartsLabel         matlab.ui.control.Label
        field_z_cutoff_RF               matlab.ui.control.NumericEditField
        field_z_averaging_window_RF     matlab.ui.control.NumericEditField
        RFt_maxsEditField               matlab.ui.control.NumericEditField
        RFt_minsEditField               matlab.ui.control.NumericEditField
        ForcefitparametersTextArea      matlab.ui.control.TextArea
        LoadingratefitparametersTextArea  matlab.ui.control.TextArea
        ForcefitparametersTextAreaLabel  matlab.ui.control.Label
        LoadingratefitparametersLabel   matlab.ui.control.Label
        plot_LR_fit                     matlab.ui.control.UIAxes
        plot_RF_fit                     matlab.ui.control.UIAxes
        plot_LR_histogram               matlab.ui.control.UIAxes
        plot_Rupture_bead               matlab.ui.control.UIAxes
        plot_RF_histogram               matlab.ui.control.UIAxes
        AutomatedstokesforcecalibrationTab  matlab.ui.container.Tab
        TipsInstructionsPanel           matlab.ui.container.Panel
        GeneralTheautomatedforcecalibrationonlyworksifalldatasetsLabel  matlab.ui.control.Label
        Loadinsampledatasetanddefineanchorpointtimerange23Label  matlab.ui.control.Label
        display_status_automatedSFC     matlab.ui.control.TextArea
        StatusTextAreaLabel             matlab.ui.control.Label
        display_filepath_automatedSFC   matlab.ui.control.TextArea
        FilePathTextAreaLabel           matlab.ui.control.Label
        SelectfilepathandrunautomatedforcecalibrationButton  matlab.ui.control.Button
        FilenameforNstructEditField     matlab.ui.control.EditField
        FilenameforNstructEditFieldLabel  matlab.ui.control.Label
        ListBox                         matlab.ui.control.ListBox
        LoaddataButton                  matlab.ui.control.Button
        plot_main_zp                    matlab.ui.control.UIAxes
        plot_main_xy                    matlab.ui.control.UIAxes
    end

    % global variables (manipulated by different functions)

    properties (Access = private)
        M; % Main data struct
        value; % bead selection from listbox
        N; % Main struct to collect beads for heatmap
        loaded_N; % temporary N struct for loading in N
        Heatmap_data; % Heatmap data struct
        temp_heatmap_data; % temporary heatmap data for loading in/saving heatmap
        RFtime; % rupture force time
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoaddataButton
        function LoaddataButtonPushed(app, event)
                        
            % initialize M and ListBox, status update and main plots
            app.M=[];
            app.ListBox.Items={};
            app.field_general_status_update.Value='';
            cla(app.plot_main_xy);
            cla(app.plot_main_zp);

            % get file path and file name
            [file_name_tdms,path] = uigetfile('*.tdms');
            full_path=strcat(path,file_name_tdms);
    
            % if cancel button is pressed, then function will be stopped here
            if isempty(full_path)
                return  
            end
            
            % update status field
            app.field_general_status_update.Value='Importing TDMS file...';
            drawnow

            % load in tracking data
            app.M=Import_TDMS(full_path,file_name_tdms); 
            
            % populate main list box
            temp_ROI_list=[app.M.ROI.bead]; %use bead column as unique identifier for any operations on the data struct
            temp_ROI_list1{length(temp_ROI_list),1}=[]; % create cell array
            for i = 1:length(temp_ROI_list1)
                app.ListBox.Items(i)= {convertStringsToChars('Bead ' + string(i))};
            end
            
            % update status field
            temp_field=app.field_general_status_update.Value;
            app.field_general_status_update.Value=[temp_field; 'Data set ', file_name_tdms,' successfully imported'];
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            % get bead selection as cell array
            app.ListBox.Multiselect = "on";
            app.value = app.ListBox.Value;

            % update plots
            update_main_plots(app.M,app.value,app.plot_main_xy,app.plot_main_zp)
        end

        % Button pushed function: FindstuckbeadsButton
        function FindstuckbeadsButtonPushed(app, event)
            % read in cut-off value for z and averaging window
            co=app.field_stuck_beads_cutoff.Value;
            w=app.field_averaging_window_drift_correction.Value;

            % find stuck beads
            stuck_b=find_stuck_beads(app.M,co,w);

            % if no beads are found
            if isempty(stuck_b)
                stuck_b='no stuck bead found';
            end

            % display stuck beads
            app.StuckBeadsTextArea.Value = num2str(stuck_b);
        end

        % Button pushed function: RemovebeadsButton
        function RemovebeadsButtonPushed(app, event)
            % find bead index and remove from list box
            [~,idx] = ismember(app.ListBox.Value,app.ListBox.Items);
            app.ListBox.Items(idx) = [];                            
            app.M.ROI(idx)=[];
        end

        % Button pushed function: ExporttrackingdataMtoworkspaceButton
        function ExporttrackingdataMtoworkspaceButtonPushed(app, event)
            % assign M to worksapce
            assignin('base','M',app.M);
        end

        % Button pushed function: DisplayaveragedtraceButton
        function DisplayaveragedtraceButtonPushed(app, event)
            % clear plots
            cla(app.plot_x_drift)
            cla(app.plot_y_drift)
            cla(app.plot_z_drift)

            % get averaging window for drift correction preview
            w=app.field_averaging_window_drift_correction.Value;
            
            % convert bead selection to correct format for function
            beads_for_correction =zeros(length(app.value),1);
            for i = 1:length(app.value)
                app.value{i} = regexp(app.value{i},'\d+','match');
                beads_for_correction(i)=str2double(app.value{1,i}{1,1});
            end

            % plot stuck beads
            plot_stuck_beads_GUI(app.M,beads_for_correction,w,app.plot_x_drift,app.plot_y_drift,app.plot_z_drift);
        end

        % Button pushed function: CorrectfordriftinzButton
        function CorrectfordriftinzButtonPushed(app, event)
            % temporary status field for display purposes
            temp_field=app.field_general_status_update.Value;

            % get averaging window for drift correction
            w=app.field_averaging_window_drift_correction.Value;

            % populate temporary array for function
            beads_for_drift_correction =[];
            for i = 1:length(app.value)
                app.value{i} = regexp(app.value{i},'\d+','match');
                beads_for_drift_correction=[beads_for_drift_correction str2double(app.value{1,i}{1,1})];
            end

            % correct for drift
            app.M = correct_drift_z(app.M,beads_for_drift_correction,w);
            
            % display beads
            if ~isempty(app.value)
                app.ChosenBeadsTextArea.Value = num2str(beads_for_drift_correction);
            end

            % append temp status field cell array and update status field
            temp_field=[temp_field; 'Traces corrected for drift'];
            app.field_general_status_update.Value=temp_field;
        end

        % Button pushed function: DetermineanchorpointButton
        function AnchorPointButtonPushed(app, event)
           
            % temporary field for status update
            temp_field=app.field_general_status_update.Value;

            % get time range indices for anchor point
            t_min = app.field_anchorpoint_start.Value;
            t_max = app.field_anchorpoint_end.Value;
            [~,idx1]=min(abs(app.M.Time-t_min));
            [~,idx2]=min(abs(app.M.Time-t_max));


            % get magnification data
            if  strcmp(app.MagnificationDropDown.Value,'4x')
                xmax = 1696;
                ymax = 1357;
            elseif strcmp(app.MagnificationDropDown.Value,'10x')
                xmax = 678;
                ymax = 543;
            elseif strcmp(app.MagnificationDropDown.Value,'20x')
                xmax = 339;
                ymax = 271;
            elseif strcmp(app.MagnificationDropDown.Value,'40x')
                xmax = 170;
                ymax = 136;
            end

            % determine anchor point
            [app.M,idxremove]=anchorpoint(app.M,idx1,idx2,xmax,ymax);
            app.ListBox.Items(idxremove) = [];  % update list box
            
            % update status field
            temp_field=[temp_field; 'Anchorpoint determined'];
            app.field_general_status_update.Value=temp_field;
        end

        % Button pushed function: PlotRMSandStdDevzhistogramsButton
        function PlotRMSandStdDevzhistogramsButtonPushed(app, event)
            % plots RMS and Standard deviation in z histograms
            histogram(app.plot_RMS_histogram,[app.M.ROI.RMS].*10^9); % conversion to nm
            histogram(app.plot_Stdev_z_histogram,[app.M.ROI.StDz].*10^9) % conversion to nm
        end

        % Button pushed function: RemoveforcefitoutliersButton
        function RemoveforcefitoutliersButtonPushed(app, event)

            % read in boundary values for parameters
            f0_lower_cut = app.folowercutpNumEditField.Value;
            f0_upper_cut = app.fouppercutpNumEditField.Value;
            kp_lower_cut = app.kplowercut1umEditField.Value;
            kp_upper_cut = app.kpuppercut1umEditField.Value;       
            phip_lower_cut = app.phiplowercutEditField.Value;
            phip_upper_cut = app.phipuppercutEditField.Value;
            rsq_lower_cut = app.RsqlowercutEditField.Value;
            rsq_upper_cut = app.RsquppercutEditField.Value;
            znode_lower_cut = app.znodelowercutumEditField.Value;
            znode_upper_cut = app.znodeuppercutumEditField.Value;

            % find and remove outliers
            app.M=remove_outliers_FC_M_struct(app.M,f0_lower_cut,f0_upper_cut,kp_lower_cut,kp_upper_cut,phip_lower_cut,phip_upper_cut,rsq_lower_cut,rsq_upper_cut,znode_lower_cut,znode_upper_cut);

            % update main list box
            app.ListBox.Items={};
            temp_ROI_list=[app.M.ROI.bead]; %use bead column as unique identifier for any operations on the data struct
            temp_ROI_list1{length(temp_ROI_list),1}=[];
            for i = 1:length(temp_ROI_list1)
                app.ListBox.Items(i)= {convertStringsToChars('Bead ' + string(temp_ROI_list(i)))};
            end

            % update histograms
            plot_force_fits_M(app.M,app.plot_fo_histogram,app.plot_kp_histogram,app.plot_phip_histogram,app.plot_Rsq_histogram,app.zNodeHistogram);
        end

        % Button pushed function: FindbeadsButton
        function FindbeadsButtonPushed(app, event)
            % clear plot
            cla(app.plot_beads_for_FC)
            
            % get time range and other inputs
            t_min = app.FCt_minsEditField.Value;
            t_max = app.FCt_maxsEditField.Value;
            [~,idx1]=min(abs(app.M.Time-t_min));
            [~,idx2]=min(abs(app.M.Time-t_max));
            co=app.zcutoffnmEditField.Value;

            % find beads for force calibration (FC_beads) and not for force
            % calibration (Out_beads)
            [FC_beads,Out_beads]=find_beads_FC_GUI(app.M,idx1,idx2,co);  

            if isempty(Out_beads) % Out_beads is an array of beads which are not suitable for force calibration.
                app.FCBeadsTextArea.Value = 'none';
            else
                app.FCBeadsTextArea.Value = num2str(Out_beads);
            end
          
            if ~isempty(FC_beads)  % execute plot only if there are bead IDs in FC_beads
                plot_beads_FC(app.plot_beads_for_FC,app.M,FC_beads);
            end
        end

        % Button pushed function: RemovebeadsbelowcutoffButton
        function RemovebeadsbelowcutoffButtonPushed(app, event)
           
            % get time range and other inputs
            t_min = app.FCt_minsEditField.Value;
            t_max = app.FCt_maxsEditField.Value;
            [~,idx1]=min(abs(app.M.Time-t_min));
            [~,idx2]=min(abs(app.M.Time-t_max));
            all_beads = [app.M.ROI.bead];
            co=app.zcutoffnmEditField.Value;

            % find bead for force calibration
            [FC_beads,~]=find_beads_FC_GUI(app.M,idx1,idx2,co);
            
            beads_to_keep = FC_beads;
            
            % remove beads from M struct and update list box
            app.M=remove_beads_FC_GUI(app.M,beads_to_keep);
            idx_remove=find(ismember(all_beads,beads_to_keep)==0);
            app.ListBox.Items(idx_remove) = [];                            
                          
        end

        % Button pushed function: ExecuteforcefitButton
        function ExecuteforcefitButtonPushed(app, event)
            
            % temporary status field for display purposes
            temp_field=app.field_general_status_update.Value;

            % update status field
            temp_field=[temp_field; 'Stokes force calibration in progress...'];
            app.field_general_status_update.Value=temp_field;
            drawnow;

            % get time indices
            t_min = app.FCt_minsEditField.Value;
            t_max = app.FCt_maxsEditField.Value;
            [~,idx1]=min(abs(app.M.Time-t_min));
            [~,idx2]=min(abs(app.M.Time-t_max));
            
            % get other values for force fit
            w=app.averagingwindowEditField.Value;
            Rmin=app.RminEditField.Value;
            dbead = app.field_bead_diameter.Value;

            % get boundary conditions and starting parameters
            lb = [app.f0EditField.Value,app.kpEditField.Value,app.phipEditField.Value];
            ub = [app.f0EditField_3.Value,app.kpEditField_3.Value,app.phipEditField_3.Value];
            x0 = [app.f0EditField_2.Value,app.kpEditField_2.Value,app.phipEditField_2.Value];
            
            % prepare bead traces for force fitting
            app.M=prep_beads_FC_GUI(app.M,idx1,idx2,w);
            
            % run the force fit and find the z-node
            app.M = stokes_force_fit(app.M, lb, ub, x0,Rmin,dbead); 
            app.M=find_z_node(app.M);

            % update status field
            temp_field=[temp_field; 'Stokes force calibration completed'];
            app.field_general_status_update.Value=temp_field;
  
        end

        % Button pushed function: PlotforcefitpertraceButton
        function PlotforcefitpertraceButtonPushed(app, event)
            %clean plot and remove legend
            cla(app.plot_force_fit_for_trace)
            legend(app.plot_force_fit_for_trace, 'off');

            % plot force fits and prepare  to obtain fit parameters
            % from M struct
            % this loop matches the bead selection for subsequent functions
            bead_num =zeros(length(app.value),1);
            for i = 1: length(app.value)
                bead_num(i,1)=str2double(regexp(app.value{i},'\d+','match'));
            end

            [index]=plot_force_fit_per_trace(app.plot_force_fit_for_trace,app.M,bead_num);

            % extract fit parameters from M struct using the indices
            % and then summarize in "data" array
            R_sq=[];
            f0=[];
            kp=[];
            phip=[];
            bead_num=[];
            z_node=[];

            for i=1:length(index)
                R_sq=[R_sq,app.M.ROI(index(i)).RSQ];
                f0=[f0,app.M.ROI(index(i)).fitp(1)];
                kp=[kp,app.M.ROI(index(i)).fitp(2)];
                phip=[phip,app.M.ROI(index(i)).fitp(3)];
                bead_num=[bead_num,app.M.ROI(index(i)).bead];
                z_node=[z_node,app.M.ROI(index(i)).znode];
            end
            data=zeros(length(R_sq),6);
            for i = 1:length(bead_num)
                data(i,1)=bead_num(i);
                data(i,2)=R_sq(i);
                data(i,3)=f0(i);
                data(i,4)=kp(i);
                data(i,5)=phip(i);
                data(i,6)=z_node(i);
            end
            %                 assignin('base','data',data); % output array to workspace if needed
            app.Bead_fit_parameter_table.Data = array2table(data); %convert data array to table before passing it to UItable


        end

        % Button pushed function: PlotforcefithistogramsButton
        function PlotforcefithistogramsButtonPushed(app, event)
            % plot force fit histograms
            plot_force_fits_M(app.M,app.plot_fo_histogram,app.plot_kp_histogram,app.plot_phip_histogram,app.plot_Rsq_histogram,app.zNodeHistogram);
        end

        % Button pushed function: PlotforceprofileButton
        function PlotforceprofileButtonPushed(app, event)
            % clear plot
            cla(app.plot_force_profile_per_trace)

            % get values from edit field
            z_end = app.z_endumEditField.Value;

            % define array
            bead_num =zeros(length(app.value),1);
            
            % this loop matches the bead selection for subsequent functions
            for i = 1: length(app.value)
                bead_num(i,1)=str2double(regexp(app.value{i},'\d+','match'));
            end

            % create array of z positions
            z_forceprofile=0:0.5:z_end; 

            % plot force profile
            plot_force_profile_GUI(app.M,bead_num,z_forceprofile,app.plot_force_profile_per_trace);
        end

        % Button pushed function: VisusalizebeadcollectionButton
        function VisusalizebeadcollectionButtonPushed(app, event)
            % get magnification data
            if  strcmp(app.MagnificationDropDown.Value,'4x')
                xmax = 1696;
                ymax = 1357;
            elseif strcmp(app.MagnificationDropDown.Value,'10x')
                xmax = 678;
                ymax = 543;
            elseif strcmp(app.MagnificationDropDown.Value,'20x')
                xmax = 339;
                ymax = 271;
            elseif strcmp(app.MagnificationDropDown.Value,'40x')
                xmax = 170;
                ymax = 136;
            end

            % get power values
            P= app.PowerEditField.Value;

            %plot a location map of the beads which have been collected so far
            visualize_bead_collection(app.plot_bead_distribution,app.N,P,xmax,ymax)
        end

        % Button pushed function: CollectbeadsforheatmapButton
        function CollectbeadsforheatmapButtonPushed(app, event)
           
            % temporary status field for display purposes
            temp_field=app.field_general_status_update.Value;
            
            % store beads in N struct and update table
            [app.N, tbeads]=collect_beads(app.M,app.N); 
             app.table_power_values.Data = array2table(tbeads);

            % update status field
            temp_field=[temp_field; 'Current data set stored for heatmap'];
            app.field_general_status_update.Value=temp_field;

        end

        % Button pushed function: FindruptureforceButton
        function FindruptureforceButtonPushed(app, event)
            
            % temporary status field for display purposes
            temp_field=app.field_general_status_update.Value;

            % get time indices and parameters for rupture force
            % determination
            t_min = app.RFt_minsEditField.Value;
            t_max = app.RFt_maxsEditField.Value;
            [~,idx1]=min(abs(app.M.Time-t_min));
            [~,idx2]=min(abs(app.M.Time-t_max));            
            rf_co = app.field_z_cutoff_RF.Value;
            rf_w = app.field_z_averaging_window_RF.Value;

            % find rupture forces
            [app.M , mbeads, ~] = determine_rupture_force_GUI(app.M,idx1,idx2,rf_w,rf_co);

            % display beads for manual rupture force inspection (mbeads)
            if isempty(mbeads)
                app.BeadsformanualRFcheckTextArea.Value ='none';
            else
                app.BeadsformanualRFcheckTextArea.Value =num2str(mbeads);
            end

            % Reinitialize prior to every AssignrupturetimeButtonPushed event
            app.field_rupture_time_input.Value = -1;  

            % update status field
            temp_field=[temp_field; 'Rupture forces determined'];
            app.field_general_status_update.Value=temp_field;
        end

        % Button pushed function: AssignrupturetimeButton
        function AssignrupturetimeButtonPushed(app, event)
            
            % temporary status field for display purposes
            temp_field=app.field_general_status_update.Value;

            % get the rutpure time input
            t = app.field_rupture_time_input.Value;
            
            % extract bead number from list box selection
            chosen_bead = regexp(app.value{1},'\d+','match');

            % assign rupture force time either through suggested rupture
            % time (t=1) or manual input (t=\1)
            if length(app.value) == 1
                if t == -1 
                    app.M=assign_rupture_time(app.M,chosen_bead,app.RFtime); % assign rupture time to bead
                    app.field_rupture_time_input.Value=app.RFtime;
                    time_RF_display=app.RFtime; % just for updating status window
                else
                    app.M=assign_rupture_time(app.M,chosen_bead,t); % assign selected RF time
                    app.suggestedRupturetimeTextArea.Value = num2str(t);
                    time_RF_display=t; % just for updating status window
                end
            end

             % update status field
            temp_field=[temp_field; app.value{1},', ',num2str(time_RF_display),' sec assigned'];
            app.field_general_status_update.Value=temp_field;
        end

        % Button pushed function: CreateheatmapButton
        function CreateheatmapButtonPushed(app, event)

            % clear text field
            app.ForcecalibrationfactorfitparamtersnormaldistributionTextArea.Value='';
            
            % get magnification data
            if  strcmp(app.MagnificationDropDown.Value,'4x')
                xmax = 1696;
                ymax = 1357;
            elseif strcmp(app.MagnificationDropDown.Value,'10x')
                xmax = 678;
                ymax = 543;
            elseif strcmp(app.MagnificationDropDown.Value,'20x')
                xmax = 339;
                ymax = 271;
            elseif strcmp(app.MagnificationDropDown.Value,'40x')
                xmax = 170;
                ymax = 136;
            end

            % get inputs for merging beads
            z_prep = app.zforforceestimationumEditField.Value;
            showbeads=app.showbeadsCheckBox_heatmap.Value;
            r_merge=app.MeshmergingsizeumEditField.Value;

            % prepare struct for merging (just rearrange N to fit
            % requirements for subequent functions)
            FC=prep_struct_for_merging(app.N,z_prep,app.plot_full_heatmap);

            % merge beads which are within the same mesh 
            Merged_beads = mergepoints_fixed_mesh_GUI(FC,r_merge,xmax,ymax);

            % do the force fit and create heatmap data
            app.Heatmap_data = power_fit(Merged_beads);


            %  Remove beads, where the force fit is off. remove them if the
            %  forces/power values are not consistent, such as a higher force at a
            %  lower power value
            app.Heatmap_data = force_check(app.Heatmap_data);
            
            % plot force factor histogram and get fit parameters
            fitpar=plot_FC_factor_histogram(app.Heatmap_data,app.plot_FC_factors);
            app.ForcecalibrationfactorfitparamtersnormaldistributionTextArea.Value=fitpar;
            
            % plot heatmap
            create_heatmap(app.plot_full_heatmap,app.Heatmap_data,xmax,ymax,showbeads);

        end

        % Button pushed function: SaveHeatmapButton
        function SaveHeatmapButtonPushed(app, event)

            % get file name and path, only allow files to be saved as *.mat
            [name,path] = uiputfile('*.mat');

            % if cancel button is pressed, then function will be stopped here
            if length(name)<=1 || length(path)<=1
                return
            end

            % save data
            Heatmap_data=app.Heatmap_data; % temporary write to local variable as a workaround for saving
            save(fullfile(path,name),'Heatmap_data');

        end

        % Button pushed function: PlotrupturebeadtraceButton
        function PlotrupturebeadtraceButtonPushed(app, event)
            % clear plot
            cla(app.plot_Rupture_bead,"reset")

            % get time indices
            t_min = app.RFt_minsEditField.Value;
            t_max = app.RFt_maxsEditField.Value;
            [~,idx1]=min(abs(app.M.Time-t_min));
            [~,idx2]=min(abs(app.M.Time-t_max));
            
            % get cut-off and averaging window
            rf_co = app.field_z_cutoff_RF.Value;
            rf_w = app.field_z_averaging_window_RF.Value;
                
            % this loop matches the bead selection for subsequent functions
            bead_num =zeros(length(app.value),1);
            for i = 1: length(app.value)
                bead_num(i,1)=str2double(regexp(app.value{i},'\d+','match'));
            end
               
            % find rupture force time for selected bead
            app.RFtime = plot_rupture_beads_manual(app.plot_Rupture_bead,app.M,idx1,idx2,rf_w,rf_co,bead_num);

            % update text field
            app.suggestedRupturetimeTextArea.Value = string(app.RFtime);

            % reinitialize to default value
            app.field_rupture_time_input.Value = -1; 
        end

        % Button pushed function: LoadHeatmapButton
        function LoadHeatmapButtonPushed(app, event)
            % clear text field and plots
            app.ForcecalibrationfactorfitparamtersnormaldistributionTextArea.Value='';
            cla(app.plot_full_heatmap);
            cla(app.plot_FC_factors);

            % get magnification data
            if  strcmp(app.MagnificationDropDown.Value,'4x')
                xmax = 1696;
                ymax = 1357;
            elseif strcmp(app.MagnificationDropDown.Value,'10x')
                xmax = 678;
                ymax = 543;
            elseif strcmp(app.MagnificationDropDown.Value,'20x')
                xmax = 339;
                ymax = 271;
            elseif strcmp(app.MagnificationDropDown.Value,'40x')
                xmax = 170;
                ymax = 136;
            end        
            
            % T/F indicator for showing beads in plot
            showbeads=app.showbeadsCheckBox_heatmap.Value;

            % select file
            [file_name_tdms,path] = uigetfile('*.mat');
            full_path=strcat(path,file_name_tdms);

            % if cancel button is pressed, then function will be stopped here
            if isempty(full_path)
                return
            end
            
            % load heatmap data
            app.temp_heatmap_data=load_heatmap_data(full_path);
            app.Heatmap_data = app.temp_heatmap_data;

            % plot force factor histogram and get fit parameters
            fitpar=plot_FC_factor_histogram(app.Heatmap_data,app.plot_FC_factors);
            app.ForcecalibrationfactorfitparamtersnormaldistributionTextArea.Value=fitpar;

            % plot heatmap
            create_heatmap(app.plot_full_heatmap,app.Heatmap_data,xmax,ymax,showbeads); 
            
        end

        % Button pushed function: 
        % PlotruptureforceandloadingratehistogramButton
        function PlotruptureforceandloadingratehistogramButtonPushed(app, event)

            % temporary status field
            temp_field=app.field_general_status_update.Value;

            % check if heatmap is loaded in
            if isempty(app.Heatmap_data)
                % display message box update status field
                msgbox('Please load in a force factor heatmap first','Error')
                temp_field=[temp_field; 'No heatmap found'];
                app.field_general_status_update.Value=temp_field;
                return
            end

            % assign rupture force based on heatmap
            app.M = assign_rupture_force(app.M,app.Heatmap_data);

            % plot rutpure force histograms
            plot_force_histogram(app.plot_RF_histogram,app.plot_LR_histogram,app.M);

            % update status field
            temp_field=[temp_field; 'Rupture forces assigned'];
            app.field_general_status_update.Value=temp_field;

        end

        % Button pushed function: LoadNstructButton
        function LoadNstructButtonPushed(app, event)
            %clear heatmap, plots and tables; N is not cleared in case N already
            %exists and data needs to be added
            app.Heatmap_data=[];
            cla(app.plot_full_heatmap);
            cla(app.plot_FC_factors);
            cla(app.plot_bead_distribution);
            app.table_power_values.Data={};
            app.ForcecalibrationfactorfitparamtersnormaldistributionTextArea.Value='';

            
            % load file
            [file_name_tdms,path] = uigetfile('*.mat');
            full_path=strcat(path,file_name_tdms);

            % if cancel button is pressed, then function will be stopped here
            if isempty(full_path)
                return  
            end

            % in case N already exists, N will be appended with the loaded N struct.  
            app.loaded_N=load_N(full_path);
            app.N = [app.N , app.loaded_N];

            % update table for visualizing beads
            temp_power=[app.N.Power]'; % write all power values to temporary array

            % taken from function "collect_beads"
            [C,~,ic] = unique(temp_power); 
            a_counts = accumarray(ic,1);
            tbeads = [C, a_counts];
            app.table_power_values.Data = array2table(tbeads);
        end

        % Button pushed function: 
        % SelectfilepathandrunautomatedforcecalibrationButton
        function SelectfilepathandrunautomatedforcecalibrationButtonPushed(app, event)
% ########################################################################
% ########### AUTOMATED FORCE CALIBRATION SCRIPT STARTS HERE##############
% ########################################################################

            % get magnification data for FoV
            if  strcmp(app.MagnificationDropDown.Value,'4x')
                xmax = 1696;
                ymax = 1357;
            elseif strcmp(app.MagnificationDropDown.Value,'10x')
                xmax = 678;
                ymax = 543;
            elseif strcmp(app.MagnificationDropDown.Value,'20x')
                xmax = 339;
                ymax = 271;
            elseif strcmp(app.MagnificationDropDown.Value,'40x')
                xmax = 170;
                ymax = 136;
            end

            % Specify which folder needs to be analyzed:
            AnalyzeFolder = uigetdir;

            % if cancel button is pressed, AnalyzeFolder is of size 1 and function is stopped
            if length(AnalyzeFolder)<=1 
                return
            end
            
            % display which folder is analyzed
            app.display_filepath_automatedSFC.Value = AnalyzeFolder;

            % switch to folder to get a list of TDMS files and switch back
            % to the folder of the GUI
            OldFolder=cd;
            cd(AnalyzeFolder)
            fileList = dir('*.tdms'); % get list of TDMS files
            cd(OldFolder); 

            % temporary status field for display purposes (general status field)
            app.field_general_status_update.Value='Automated SFC started';
            temp_field=app.field_general_status_update.Value;

            % clear automated SFC status field
            app.display_status_automatedSFC.Value='';
            %% Initialize N
            % if there is an error during automated FC, some M structs are
            % already moved to N and N gets appended if a new set of
            % automated analysis is selected. This causes multiple entries
            % of the same data set (duplicates).
            app.N=[];

            %% Import each file one by one and analyze
            cbeads=[]; % counter for beads (for information only, no use in any functions)
            a = -2; % a,b,c are just running variables used for the proper 
            % display of the status update in the SFC automation tab.
            % Calling each line individually is more efficient
            b = -1;
            c = 0;
            for i=1:length(fileList)
                a = a+3;
                b = b+3;
                c = c+3;
                %% Import file

                % construct file path
                fullpath=strcat(fileList(i).folder,'\',fileList(i).name);

                % update status field
                app.display_status_automatedSFC.Value{a} = ['Importing set ',num2str(i), '/ ', num2str(length(fileList))] ;
                drawnow;

                % import tracking data
                app.M=Import_TDMS(fullpath,[]);

                % count how many beads are in the file and keep track of how many are
                % analyzed in total by appending the array
                cbeads=[cbeads length(app.M.ROI)];

                %% Determine anchor point

                % get time range (indices) for anchor point
                t_min = app.field_anchorpoint_start.Value;
                t_max = app.field_anchorpoint_end.Value;
                [~,idx1]=min(abs(app.M.Time-t_min));
                [~,idx2]=min(abs(app.M.Time-t_max));

                % determine achor point
                [app.M,~]=anchorpoint(app.M,idx1,idx2,xmax,ymax);

                % remove beads above a standard deviation in z. High standard deviation
                % in z usually indicates tracking errors.
                std_dev_cut=app.StdDevzcutoffnmEditField.Value;
                std_dev_cut=std_dev_cut*10^-9; %conversion to meter
                app.M=remove_beads_StDz(app.M,std_dev_cut);

                %% Do the Stokes Force Calibration:

                % get time range (indices) for SFC
                t_min = app.FCt_minsEditField.Value;
                t_max = app.FCt_maxsEditField.Value;
                [~,idx1]=min(abs(app.M.Time-t_min));
                [~,idx2]=min(abs(app.M.Time-t_max));

                % get cut-off in z and averaging window
                z_co = app.zcutoffnmEditField.Value;
                w = app.averagingwindowEditField.Value;

                % find suitable beads for force calibration
                [FC_beads]=find_beads_FC_GUI_Auto(app.M,idx1,idx2,z_co);

                % remove beads below cut-off (z_co)
                app.M=remove_beads_FC_GUI(app.M,FC_beads); % no update of listbox needed, since nothing is displayed

                % prep beads for force calibration
                app.M=prep_beads_FC_GUI(app.M,idx1,idx2,w);

                % get starting parameters and boundary conditions for fit
                lb = [app.f0EditField.Value,app.kpEditField.Value,app.phipEditField.Value];
                ub = [app.f0EditField_3.Value,app.kpEditField_3.Value,app.phipEditField_3.Value];
                x0 = [app.f0EditField_2.Value,app.kpEditField_2.Value,app.phipEditField_2.Value];
                dbead = app.field_bead_diameter.Value;
                Rmin=app.RminEditField.Value;

                % update status field
                app.display_status_automatedSFC.Value{b} = ['Fitting SFC for set ',num2str(i), '/ ', num2str(length(fileList))]  ;
                drawnow;

                % perform the actual the force fit
                app.M= stokes_force_fit(app.M, lb, ub, x0,Rmin,dbead);

                %% Remove outliers based on R^2

                app.M=remove_beads_RSQ(app.M,Rmin);

                %% add the z-node

                app.M=find_z_node(app.M);

                %% Collect beads in N struct

                % append N struct
                [app.N, ~]=collect_beads(app.M,app.N);

                % update status field
                app.display_status_automatedSFC.Value{c} = ['Analysis of ',num2str(i), '/ ', num2str(length(fileList)),' complete'] ;
                drawnow;
            end

            %% Filter out based on Z-score for fo, kp, phip, and z-node

            app.N=filter_fit_Zscore(app.N);

            %% Save final N struct as *.mat file

            % get fille name
            inputstring=app.FilenameforNstructEditField.Value;

            % update table for visualizing beads
            temp_power=[app.N.Power]'; % write all power values to temporary array

            % taken from function "collect_beads"
            [C,~,ic] = unique(temp_power);
            a_counts = accumarray(ic,1);
            tbeads = [C, a_counts];
            app.table_power_values.Data = array2table(tbeads);

            % display number of beads analyzed (cbeads) and kept
            totalbeads=sum(cbeads);
            app.display_status_automatedSFC.Value{c+1}=[num2str(totalbeads), ' beads analyzed'];
            app.display_status_automatedSFC.Value{c+2}=[num2str(length(app.N)), ' beads kept'];

            % save file in same folder as data
            N=app.N; % workaround for saving N
            save(fullfile(AnalyzeFolder,inputstring),'N');

            % update SFC status field
            app.display_status_automatedSFC.Value{c+3}= 'N struct successfully saved';
            app.display_status_automatedSFC.Value{c+4}= 'Automated SFC completed';

            % update general status field
            temp_field=[temp_field; 'Automated SFC completed'];
            app.field_general_status_update.Value=temp_field;
           
% #########################################################################
% ############## END OF AUTOMATED FORCE CALIBRATION #######################
% #########################################################################
        end

        % Button pushed function: VisualizeforcedistributioninFoVButton
        function VisualizeforcedistributioninFoVButtonPushed(app, event)
            % get the correct scale            
            if  strcmp(app.MagnificationDropDown.Value,'4x')
                xmax = 1696;
                ymax = 1357;
            elseif strcmp(app.MagnificationDropDown.Value,'10x')
                xmax = 678;
                ymax = 543;
            elseif strcmp(app.MagnificationDropDown.Value,'20x')
                xmax = 339;
                ymax = 271;
            elseif strcmp(app.MagnificationDropDown.Value,'40x')
                xmax = 170;
                ymax = 136;
            end

            % get the force and z limits for the colorbar of the heat maps
            Fmin=app.FminEditField.Value;
            Fmax=app.FmaxEditField.Value;
            zmin=app.zminEditField.Value;
            zmax=app.zmaxEditField.Value;
            showbeads=app.showbeadsCheckBox.Value;

            % plot heatmaps for force and z-node
            app.M=visualize_force_distribution(app.M,xmax,ymax,Fmin,Fmax,zmin,zmax,showbeads); 

        end

        % Button pushed function: RemoveRMSandStdDevzoutliersButton
        function RemoveRMSandStdDevzoutliersButtonPushed(app, event)
            % get cut-off values
            rms_lower_cut = app.RMSlowercutnmEditField.Value;
            rms_upper_cut = app.RMSuppercutnmEditField.Value;
            std_dev_cut = app.StdDevzcutoffnmEditField.Value;

            % find and remove outliers
            app.M=remove_outliers_RMS_M_struct(app.M,rms_lower_cut,rms_upper_cut,std_dev_cut);

            % update main list box
            app.ListBox.Items={};
            temp_ROI_list=[app.M.ROI.bead]; %use bead column as unique identifier for any operations on the data struct
            temp_ROI_list1{length(temp_ROI_list),1}=[]; 
            for i = 1:length(temp_ROI_list1)
                app.ListBox.Items(i)= {convertStringsToChars('Bead ' + string(temp_ROI_list(i)))};
            end

            % update histograms
            histogram(app.plot_RMS_histogram,[app.M.ROI.RMS].*10^9); % conversion to nm
            histogram(app.plot_Stdev_z_histogram,[app.M.ROI.StDz].*10^9) % conversion to nm
        end

        % Button pushed function: ExportruptureforcedataButton
        function ExportruptureforcedataButtonPushed(app, event)
            % temporary status field
            temp_field=app.field_general_status_update.Value;

            % read in file name and path
            filename =  app.field_RF_data_filename_export.Value;
            filepath=app.field_RF_data_filepath_export.Value;

            % save data
            export_RF_data(app.M,filename,filepath)

            % update general status field
            temp_field=[temp_field; 'Data successfully exported'];
            app.field_general_status_update.Value=temp_field;
        end

        % Button pushed function: FitloadingrateandruptureforceButton
        function FitloadingrateandruptureforceButtonPushed(app, event)
            
            % get dropdown values
            fit_type_LR = app.LoadingratefitDropDown.Value;
            fit_type_RF = app.ForcefitDropDown.Value;

            % assign fit choice to loading rate and rupture force
            if strcmp(fit_type_LR,'normal')
                LR_Fit_Choice = 'normal';
            end
            if strcmp(fit_type_LR,'lognormal')
                LR_Fit_Choice = 'lognormal';
            end
            if strcmp(fit_type_RF,'normal')
                RF_Fit_Choice = 'normal';
            end
            if strcmp(fit_type_RF,'lognormal')
                RF_Fit_Choice = 'lognormal';
            end
    
            % perform rupture force fit
            [LR_param, RF_param] = plot_histogram_fits(app.M,app.plot_RF_fit,app.plot_LR_fit,RF_Fit_Choice,LR_Fit_Choice);
            
            % update text boxes
            app.LoadingratefitparametersTextArea.Value = (LR_param);
            app.ForcefitparametersTextArea.Value = (RF_param);
        end

        % Button pushed function: PlotparameterhistogramsButton
        function PlotparameterhistogramsButtonPushed(app, event)
            % plots force fit parameters of N struct
            plot_force_fits_N(app.N,app.plot_fo_histogram,app.plot_kp_histogram,app.plot_phip_histogram,app.plot_Rsq_histogram,app.zNodeHistogram);
        end

        % Button pushed function: RemoveparameteroutliersButton
        function RemoveparameteroutliersButtonPushed(app, event)

            % get cut-off values
            f0_lower_cut = app.folowercutpNumEditField.Value;
            f0_upper_cut = app.fouppercutpNumEditField.Value;
            kp_lower_cut = app.kplowercut1umEditField.Value;
            kp_upper_cut = app.kpuppercut1umEditField.Value;
            phip_lower_cut = app.phiplowercutEditField.Value;
            phip_upper_cut = app.phipuppercutEditField.Value;
            rsq_lower_cut = app.RsqlowercutEditField.Value;
            rsq_upper_cut = app.RsquppercutEditField.Value;
            znode_lower_cut = app.znodelowercutumEditField.Value;
            znode_upper_cut = app.znodeuppercutumEditField.Value;

            % remove outliers
            app.N=remove_outliers_N_struct(app.N,f0_lower_cut,f0_upper_cut,kp_lower_cut,kp_upper_cut,phip_lower_cut,phip_upper_cut,rsq_lower_cut,rsq_upper_cut,znode_lower_cut,znode_upper_cut);

            %update plots
            plot_force_fits_N(app.N,app.plot_fo_histogram,app.plot_kp_histogram,app.plot_phip_histogram,app.plot_Rsq_histogram,app.zNodeHistogram);

            % update table for visualizing beads
            temp_power=[app.N.Power]'; % write all power values to temporary array
            % taken straight from collect_beads function
            [C,~,ic] = unique(temp_power);
            a_counts = accumarray(ic,1);
            tbeads = [C, a_counts];
            app.table_power_values.Data = array2table(tbeads);
        end

        % Button pushed function: ExportNtoworkspaceButton
        function ExportNtoworkspaceButtonPushed(app, event)
            % save N to workspace
            assignin('base','N',app.N);
        end

        % Button pushed function: SamplepowerfitButton
        function SamplepowerfitButtonPushed(app, event)
            
            % get magnification data
            if  strcmp(app.MagnificationDropDown.Value,'4x')
                xmax = 1696;
                ymax = 1357;
            elseif strcmp(app.MagnificationDropDown.Value,'10x')
                xmax = 678;
                ymax = 543;
            elseif strcmp(app.MagnificationDropDown.Value,'20x')
                xmax = 339;
                ymax = 271;
            elseif strcmp(app.MagnificationDropDown.Value,'40x')
                xmax = 170;
                ymax = 136;
            end
             
            % execute function for pop-up windows
            sample_heatmap(app.Heatmap_data,xmax,ymax);
        end

        % Value changed function: showbeadsCheckBox_heatmap
        function showbeadsCheckBox_heatmapValueChanged(app, event)
            % get magnification data
            if  strcmp(app.MagnificationDropDown.Value,'4x')
                xmax = 1696;
                ymax = 1357;
            elseif strcmp(app.MagnificationDropDown.Value,'10x')
                xmax = 678;
                ymax = 543;
            elseif strcmp(app.MagnificationDropDown.Value,'20x')
                xmax = 339;
                ymax = 271;
            elseif strcmp(app.MagnificationDropDown.Value,'40x')
                xmax = 170;
                ymax = 136;
            end
            
            % get T/F value from checkbox
            showbeads = app.showbeadsCheckBox_heatmap.Value;
            
            % check if heatmap exists
            a=app.Heatmap_data;
            if isempty(a)
                return
            end

            % plot heatmap
            create_heatmap(app.plot_full_heatmap,app.Heatmap_data,xmax,ymax,showbeads); 
        end

        % Button pushed function: ClearNButton
        function ClearNButtonPushed(app, event)
            app.N=[]; % clear N
            app.table_power_values.Data={}; % clear table
            cla(app.plot_bead_distribution); % clear plot
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.plot_main_xy = uiaxes(app.UIFigure);
            title(app.plot_main_xy, 'X and Y vs. Time')
            xlabel(app.plot_main_xy, 'Time (s)')
            ylabel(app.plot_main_xy, 'Planar position (nm)')
            zlabel(app.plot_main_xy, 'Z')
            app.plot_main_zp = uiaxes(app.UIFigure);
            title(app.plot_main_zp, 'Z and Power vs. Time')
            xlabel(app.plot_main_zp, 'Time (s)')
            ylabel(app.plot_main_zp, 'Normal position (nm)')
            zlabel(app.plot_main_zp, 'Z')
            app.LoaddataButton = uibutton(app.UIFigure, 'push');
            app.LoaddataButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataButtonPushed, true);
            app.LoaddataButton.Text = 'Load data';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AnchorpointanddriftcorrectionTab = uitab(app.TabGroup);
            app.AnchorpointanddriftcorrectionTab.Title = 'Anchor point and drift correction';
            app.plot_x_drift = uiaxes(app.AnchorpointanddriftcorrectionTab);
            xlabel(app.plot_x_drift, 'Time (s)')
            ylabel(app.plot_x_drift, 'x (nm)')
            zlabel(app.plot_x_drift, 'Z')
            app.plot_y_drift = uiaxes(app.AnchorpointanddriftcorrectionTab);
            xlabel(app.plot_y_drift, 'Time (s)')
            ylabel(app.plot_y_drift, 'y (nm)')
            zlabel(app.plot_y_drift, 'Z')
            app.plot_z_drift = uiaxes(app.AnchorpointanddriftcorrectionTab);
            xlabel(app.plot_z_drift, 'Time (s)')
            ylabel(app.plot_z_drift, 'z (nm)')
            zlabel(app.plot_z_drift, 'Z')
            app.AnchorpointPanel = uipanel(app.AnchorpointanddriftcorrectionTab);
            app.AnchorpointPanel.Title = 'Anchor point';
            app.AnchorpointstartsLabel = uilabel(app.AnchorpointPanel);
            app.AnchorpointstartsLabel.Text = 'Anchor point start (s)';
            app.field_anchorpoint_start = uieditfield(app.AnchorpointPanel, 'numeric');
            app.field_anchorpoint_start.Tooltip = {'precise time range needed'};
            app.AnchorpointendsLabel = uilabel(app.AnchorpointPanel);
            app.AnchorpointendsLabel.Text = 'Anchor point end (s)';
            app.field_anchorpoint_end = uieditfield(app.AnchorpointPanel, 'numeric');
            app.field_anchorpoint_end.Tooltip = {'precise time range needed'};
            app.field_anchorpoint_end.Value = 55;
            app.DetermineanchorpointButton = uibutton(app.AnchorpointPanel, 'push');
            app.DetermineanchorpointButton.ButtonPushedFcn = createCallbackFcn(app, @AnchorPointButtonPushed, true);
            app.DetermineanchorpointButton.Text = 'Determine anchor point';
            app.DriftcorrectionPanel = uipanel(app.AnchorpointanddriftcorrectionTab);
            app.DriftcorrectionPanel.Title = 'Drift correction';
            app.CutoffinznmLabel = uilabel(app.DriftcorrectionPanel);
            app.CutoffinznmLabel.Text = 'Cut-off in z (nm)';
            app.field_stuck_beads_cutoff = uieditfield(app.DriftcorrectionPanel, 'numeric');
            app.field_stuck_beads_cutoff.Value = 1000;
            app.FindstuckbeadsButton = uibutton(app.DriftcorrectionPanel, 'push');
            app.FindstuckbeadsButton.ButtonPushedFcn = createCallbackFcn(app, @FindstuckbeadsButtonPushed, true);
            app.FindstuckbeadsButton.Text = 'Find stuck beads';
            app.CorrectfordriftinzButton = uibutton(app.DriftcorrectionPanel, 'push');
            app.CorrectfordriftinzButton.ButtonPushedFcn = createCallbackFcn(app, @CorrectfordriftinzButtonPushed, true);
            app.CorrectfordriftinzButton.Text = 'Correct for drift in z';
            app.BeadssuggestedfordriftcorrectionLabel = uilabel(app.DriftcorrectionPanel);
            app.BeadssuggestedfordriftcorrectionLabel.Text = 'Beads suggested for drift correction';
            app.StuckBeadsTextArea = uitextarea(app.DriftcorrectionPanel);
            app.ChosenBeadsTextArea = uitextarea(app.DriftcorrectionPanel);
            app.BeadsusedfordriftcorrectionLabel = uilabel(app.DriftcorrectionPanel);
            app.BeadsusedfordriftcorrectionLabel.Text = 'Beads used for drift correction';
            app.DisplayaveragedtraceButton = uibutton(app.DriftcorrectionPanel, 'push');
            app.DisplayaveragedtraceButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayaveragedtraceButtonPushed, true);
            app.DisplayaveragedtraceButton.Text = 'Display averaged trace';
            app.AveragingwindowLabel = uilabel(app.DriftcorrectionPanel);
            app.AveragingwindowLabel.Text = 'Averaging window (-)';
            app.field_averaging_window_drift_correction = uieditfield(app.DriftcorrectionPanel, 'numeric');
            app.field_averaging_window_drift_correction.Value = 10;
            app.FiltertracesTab = uitab(app.TabGroup);
            app.FiltertracesTab.Title = 'Filter traces';
            app.plot_RMS_histogram = uiaxes(app.FiltertracesTab);
            title(app.plot_RMS_histogram, 'RMS Histogram')
            xlabel(app.plot_RMS_histogram, 'RMS (nm)')
            ylabel(app.plot_RMS_histogram, 'Entries')
            zlabel(app.plot_RMS_histogram, 'Z')
            app.plot_fo_histogram = uiaxes(app.FiltertracesTab);
            title(app.plot_fo_histogram, 'fo Histogram')
            xlabel(app.plot_fo_histogram, 'f_o (pN \mum)')
            ylabel(app.plot_fo_histogram, 'Entries')
            zlabel(app.plot_fo_histogram, 'Z')
            app.plot_kp_histogram = uiaxes(app.FiltertracesTab);
            title(app.plot_kp_histogram, 'kp Histogram')
            xlabel(app.plot_kp_histogram, 'k_p (1/\mum)')
            ylabel(app.plot_kp_histogram, 'Entries')
            zlabel(app.plot_kp_histogram, 'Z')
            app.plot_phip_histogram = uiaxes(app.FiltertracesTab);
            title(app.plot_phip_histogram, 'phip Histogram')
            xlabel(app.plot_phip_histogram, '\phi_p (-)')
            ylabel(app.plot_phip_histogram, 'Entries')
            zlabel(app.plot_phip_histogram, 'Z')
            app.plot_Rsq_histogram = uiaxes(app.FiltertracesTab);
            title(app.plot_Rsq_histogram, 'R_{sq} Histogram')
            xlabel(app.plot_Rsq_histogram, 'R_{sq} (-)')
            ylabel(app.plot_Rsq_histogram, 'Entries')
            zlabel(app.plot_Rsq_histogram, 'Z')
            app.plot_Stdev_z_histogram = uiaxes(app.FiltertracesTab);
            title(app.plot_Stdev_z_histogram, 'Std. Dev. (z) Histogram')
            xlabel(app.plot_Stdev_z_histogram, 'Standard deviation (nm)')
            ylabel(app.plot_Stdev_z_histogram, 'Entries')
            zlabel(app.plot_Stdev_z_histogram, 'Z')
            app.zNodeHistogram = uiaxes(app.FiltertracesTab);
            title(app.zNodeHistogram, 'z-node Histogram')
            xlabel(app.zNodeHistogram, 'z-node (\mum)')
            ylabel(app.zNodeHistogram, 'Entries')
            zlabel(app.zNodeHistogram, 'Z')
            app.RMSlowercutnmEditFieldLabel = uilabel(app.FiltertracesTab);
            app.RMSlowercutnmEditFieldLabel.Text = 'RMS lower cut (nm)';
            app.RMSlowercutnmEditField = uieditfield(app.FiltertracesTab, 'numeric');
            app.RMSlowercutnmEditField.Value = 5;
            app.folowercutpNumEditFieldLabel = uilabel(app.FiltertracesTab);
            app.folowercutpNumEditFieldLabel.Text = 'fo lower cut (pN um)';
            app.folowercutpNumEditField = uieditfield(app.FiltertracesTab, 'numeric');
            app.folowercutpNumEditField.Value = 5;
            app.kplowercut1umEditFieldLabel = uilabel(app.FiltertracesTab);
            app.kplowercut1umEditFieldLabel.Text = 'kp lower cut (1/um)';
            app.kplowercut1umEditField = uieditfield(app.FiltertracesTab, 'numeric');
            app.kplowercut1umEditField.Value = 0.02;
            app.phiplowercutEditFieldLabel = uilabel(app.FiltertracesTab);
            app.phiplowercutEditFieldLabel.Text = 'phip lower cut (-)';
            app.phiplowercutEditField = uieditfield(app.FiltertracesTab, 'numeric');
            app.phiplowercutEditField.Value = 0.1;
            app.RMSuppercutnmEditFieldLabel = uilabel(app.FiltertracesTab);
            app.RMSuppercutnmEditFieldLabel.Text = 'RMS upper cut (nm)';
            app.RMSuppercutnmEditField = uieditfield(app.FiltertracesTab, 'numeric');
            app.RMSuppercutnmEditField.Value = 1000;
            app.fouppercutpNumEditFieldLabel = uilabel(app.FiltertracesTab);
            app.fouppercutpNumEditFieldLabel.Text = 'fo upper cut (pN um)';
            app.fouppercutpNumEditField = uieditfield(app.FiltertracesTab, 'numeric');
            app.fouppercutpNumEditField.Value = 100;
            app.kpuppercut1umEditFieldLabel = uilabel(app.FiltertracesTab);
            app.kpuppercut1umEditFieldLabel.Text = 'kp upper cut (1/um)';
            app.kpuppercut1umEditField = uieditfield(app.FiltertracesTab, 'numeric');
            app.kpuppercut1umEditField.Value = 0.07;
            app.phipuppercutEditFieldLabel = uilabel(app.FiltertracesTab);
            app.phipuppercutEditFieldLabel.Text = 'phip upper cut (-)';
            app.phipuppercutEditField = uieditfield(app.FiltertracesTab, 'numeric');
            app.phipuppercutEditField.Value = 0.9;
            app.StdDevzcutoffnmEditFieldLabel = uilabel(app.FiltertracesTab);
            app.StdDevzcutoffnmEditFieldLabel.Text = 'Std.Dev. (z) cutoff (nm)';
            app.StdDevzcutoffnmEditField = uieditfield(app.FiltertracesTab, 'numeric');
            app.StdDevzcutoffnmEditField.Value = 2000;
            app.RsqlowercutEditFieldLabel = uilabel(app.FiltertracesTab);
            app.RsqlowercutEditFieldLabel.Text = 'Rsq lower cut (-)';
            app.RsqlowercutEditField = uieditfield(app.FiltertracesTab, 'numeric');
            app.RsqlowercutEditField.Value = 0.99;
            app.znodelowercutumEditFieldLabel = uilabel(app.FiltertracesTab);
            app.znodelowercutumEditFieldLabel.Text = 'z-node lower cut (um)';
            app.znodelowercutumEditField = uieditfield(app.FiltertracesTab, 'numeric');
            app.znodelowercutumEditField.Value = 15;
            app.PlotRMSandStdDevzhistogramsButton = uibutton(app.FiltertracesTab, 'push');
            app.PlotRMSandStdDevzhistogramsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRMSandStdDevzhistogramsButtonPushed, true);
            app.PlotRMSandStdDevzhistogramsButton.Text = 'Plot RMS and Std. Dev. (z) histograms';
            app.RsquppercutEditFieldLabel = uilabel(app.FiltertracesTab);
            app.RsquppercutEditFieldLabel.Text = 'Rsq upper cut (-)';
            app.RsquppercutEditField = uieditfield(app.FiltertracesTab, 'numeric');
            app.RsquppercutEditField.Value = 1;
            app.znodeuppercutumEditFieldLabel = uilabel(app.FiltertracesTab);
            app.znodeuppercutumEditFieldLabel.Text = 'z-node upper cut (um)';
            app.znodeuppercutumEditField = uieditfield(app.FiltertracesTab, 'numeric');
            app.znodeuppercutumEditField.Value = 25;
            app.RemoveRMSandStdDevzoutliersButton = uibutton(app.FiltertracesTab, 'push');
            app.RemoveRMSandStdDevzoutliersButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveRMSandStdDevzoutliersButtonPushed, true);
            app.RemoveRMSandStdDevzoutliersButton.Text = 'Remove RMS and Std. Dev. (z) outliers';
            app.ForindividualFCfitdataonlyPanel = uipanel(app.FiltertracesTab);
            app.ForindividualFCfitdataonlyPanel.Title = 'For individual FC fit data only';
            app.PlotforcefithistogramsButton = uibutton(app.ForindividualFCfitdataonlyPanel, 'push');
            app.PlotforcefithistogramsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotforcefithistogramsButtonPushed, true);
            app.PlotforcefithistogramsButton.Text = 'Plot force fit histograms';
            app.RemoveforcefitoutliersButton = uibutton(app.ForindividualFCfitdataonlyPanel, 'push');
            app.RemoveforcefitoutliersButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveforcefitoutliersButtonPushed, true);
            app.RemoveforcefitoutliersButton.Text = 'Remove  force fit outliers';
            app.Label = uilabel(app.ForindividualFCfitdataonlyPanel);
            app.Label.Text = 'For filtering of collected FC data (N), use buttons in Heatmap generation tab';
            app.ForcecalibrationTab = uitab(app.TabGroup);
            app.ForcecalibrationTab.Title = 'Force calibration';
            app.plot_beads_for_FC = uiaxes(app.ForcecalibrationTab);
            title(app.plot_beads_for_FC, 'Beads for force calibration')
            xlabel(app.plot_beads_for_FC, 'Time (s)')
            ylabel(app.plot_beads_for_FC, 'z (\mum)')
            zlabel(app.plot_beads_for_FC, 'Z')
            app.plot_force_fit_for_trace = uiaxes(app.ForcecalibrationTab);
            title(app.plot_force_fit_for_trace, 'Force fit per trace')
            xlabel(app.plot_force_fit_for_trace, 'Time (s)')
            ylabel(app.plot_force_fit_for_trace, 'z (\mum)')
            zlabel(app.plot_force_fit_for_trace, 'Z')
            app.plot_force_profile_per_trace = uiaxes(app.ForcecalibrationTab);
            title(app.plot_force_profile_per_trace, 'Force profile per trace')
            xlabel(app.plot_force_profile_per_trace, 'z (\mum)')
            ylabel(app.plot_force_profile_per_trace, 'Force (pN)')
            zlabel(app.plot_force_profile_per_trace, 'Z')
            app.FindbeadsforforcecalibrationPanel = uipanel(app.ForcecalibrationTab);
            app.FindbeadsforforcecalibrationPanel.Title = 'Find beads for force calibration';
            app.zcutoffumLabel = uilabel(app.FindbeadsforforcecalibrationPanel);
            app.zcutoffumLabel.Text = 'z cut-off (um)';
            app.zcutoffnmEditField = uieditfield(app.FindbeadsforforcecalibrationPanel, 'numeric');
            app.zcutoffnmEditField.ValueDisplayFormat = '%.0f';
            app.zcutoffnmEditField.Value = 10;
            app.ForcecalibrationstartsLabel = uilabel(app.FindbeadsforforcecalibrationPanel);
            app.ForcecalibrationstartsLabel.Text = 'Force calibration start (s)';
            app.FCt_minsEditField = uieditfield(app.FindbeadsforforcecalibrationPanel, 'numeric');
            app.FCt_minsEditField.Tooltip = {'Only approximate time range needed.'};
            app.FCt_minsEditField.Value = 14;
            app.ForcecalibrationendsLabel = uilabel(app.FindbeadsforforcecalibrationPanel);
            app.ForcecalibrationendsLabel.Text = 'Force calibration end (s)';
            app.FCt_maxsEditField = uieditfield(app.FindbeadsforforcecalibrationPanel, 'numeric');
            app.FCt_maxsEditField.Tooltip = {'Only approximate time range needed.'};
            app.FCt_maxsEditField.Value = 17;
            app.FindbeadsButton = uibutton(app.FindbeadsforforcecalibrationPanel, 'push');
            app.FindbeadsButton.ButtonPushedFcn = createCallbackFcn(app, @FindbeadsButtonPushed, true);
            app.FindbeadsButton.Text = 'Find beads';
            app.RemovebeadsbelowcutoffButton = uibutton(app.FindbeadsforforcecalibrationPanel, 'push');
            app.RemovebeadsbelowcutoffButton.ButtonPushedFcn = createCallbackFcn(app, @RemovebeadsbelowcutoffButtonPushed, true);
            app.RemovebeadsbelowcutoffButton.Text = 'Remove beads below cut-off';
            app.ForcefitsettingsPanel = uipanel(app.ForcecalibrationTab);
            app.ForcefitsettingsPanel.Title = 'Force fit settings';
            app.ExecuteforcefitButton = uibutton(app.ForcefitsettingsPanel, 'push');
            app.ExecuteforcefitButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteforcefitButtonPushed, true);
            app.ExecuteforcefitButton.Text = 'Execute force fit';
            app.lowerbondLabel = uilabel(app.ForcefitsettingsPanel);
            app.lowerbondLabel.Text = 'lower bond';
            app.startingvalueLabel = uilabel(app.ForcefitsettingsPanel);
            app.startingvalueLabel.Text = 'starting value';
            app.upperboundLabel = uilabel(app.ForcefitsettingsPanel);
            app.upperboundLabel.Text = 'upper bound';
            app.fopNumLabel = uilabel(app.ForcefitsettingsPanel);
            app.fopNumLabel.Text = 'fo (pN*um)';
            app.f0EditField = uieditfield(app.ForcefitsettingsPanel, 'numeric');
            app.f0EditField_2 = uieditfield(app.ForcefitsettingsPanel, 'numeric');
            app.f0EditField_2.Value = 30;
            app.f0EditField_3 = uieditfield(app.ForcefitsettingsPanel, 'numeric');
            app.f0EditField_3.Value = 200;
            app.kp1umLabel = uilabel(app.ForcefitsettingsPanel);
            app.kp1umLabel.Text = 'kp (1/um)';
            app.kpEditField = uieditfield(app.ForcefitsettingsPanel, 'numeric');
            app.kpEditField_2 = uieditfield(app.ForcefitsettingsPanel, 'numeric');
            app.kpEditField_2.Value = 0.046;
            app.kpEditField_3 = uieditfield(app.ForcefitsettingsPanel, 'numeric');
            app.kpEditField_3.Value = 0.1;
            app.phipLabel = uilabel(app.ForcefitsettingsPanel);
            app.phipLabel.Text = 'phip (-)';
            app.phipEditField = uieditfield(app.ForcefitsettingsPanel, 'numeric');
            app.phipEditField_2 = uieditfield(app.ForcefitsettingsPanel, 'numeric');
            app.phipEditField_2.Value = 0.799;
            app.phipEditField_3 = uieditfield(app.ForcefitsettingsPanel, 'numeric');
            app.phipEditField_3.Value = 1.5707963267949;
            app.RminLabel = uilabel(app.ForcefitsettingsPanel);
            app.RminLabel.Text = 'Rmin (-)';
            app.RminEditField = uieditfield(app.ForcefitsettingsPanel, 'numeric');
            app.RminEditField.Value = 0.99;
            app.AveragingwindowforzLabel = uilabel(app.ForcefitsettingsPanel);
            app.AveragingwindowforzLabel.Text = 'Averaging window for z (-)';
            app.averagingwindowEditField = uieditfield(app.ForcefitsettingsPanel, 'numeric');
            app.averagingwindowEditField.ValueDisplayFormat = '%.0f';
            app.averagingwindowEditField.Value = 1;
            app.ForcefitinspectionPanel = uipanel(app.ForcecalibrationTab);
            app.ForcefitinspectionPanel.Title = 'Force fit inspection';
            app.lowerlimitLabel = uilabel(app.ForcefitinspectionPanel);
            app.lowerlimitLabel.Text = 'lower limit';
            app.upperlimitLabel = uilabel(app.ForcefitinspectionPanel);
            app.upperlimitLabel.Text = 'upper limit';
            app.ForcerangepNLabel = uilabel(app.ForcefitinspectionPanel);
            app.ForcerangepNLabel.Text = 'Force range (pN)';
            app.FminEditField = uieditfield(app.ForcefitinspectionPanel, 'numeric');
            app.FminEditField.Tooltip = {'for visualization only'};
            app.FmaxEditField = uieditfield(app.ForcefitinspectionPanel, 'numeric');
            app.FmaxEditField.Tooltip = {'for visualization only'};
            app.FmaxEditField.Value = 5;
            app.znoderangeumLabel = uilabel(app.ForcefitinspectionPanel);
            app.znoderangeumLabel.Text = 'z-node range (um)';
            app.zminEditField = uieditfield(app.ForcefitinspectionPanel, 'numeric');
            app.zminEditField.Tooltip = {'for visualization only'};
            app.zminEditField.Value = 15;
            app.zmaxEditField = uieditfield(app.ForcefitinspectionPanel, 'numeric');
            app.zmaxEditField.Tooltip = {'for visualization only'};
            app.zmaxEditField.Value = 25;
            app.VisualizeforcedistributioninFoVButton = uibutton(app.ForcefitinspectionPanel, 'push');
            app.VisualizeforcedistributioninFoVButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeforcedistributioninFoVButtonPushed, true);
            app.VisualizeforcedistributioninFoVButton.Text = 'Visualize force distribution in FoV';
            app.showbeadsCheckBox = uicheckbox(app.ForcefitinspectionPanel);
            app.showbeadsCheckBox.Text = 'show beads?';
            app.showbeadsCheckBox.Value = true;
            app.PlotforcefitpertraceButton = uibutton(app.ForcefitinspectionPanel, 'push');
            app.PlotforcefitpertraceButton.ButtonPushedFcn = createCallbackFcn(app, @PlotforcefitpertraceButtonPushed, true);
            app.PlotforcefitpertraceButton.Text = 'Plot force fit per trace';
            app.PlotforceprofileButton = uibutton(app.ForcefitinspectionPanel, 'push');
            app.PlotforceprofileButton.ButtonPushedFcn = createCallbackFcn(app, @PlotforceprofileButtonPushed, true);
            app.PlotforceprofileButton.Text = 'Plot force profile';
            app.zrangeumLabel = uilabel(app.ForcefitinspectionPanel);
            app.zrangeumLabel.Text = 'z range (um)';
            app.z_endumEditField = uieditfield(app.ForcefitinspectionPanel, 'numeric');
            app.z_endumEditField.Limits = [0 Inf];
            app.z_endumEditField.Value = 50;
            app.CollectbeadsforheatmapButton = uibutton(app.ForcefitinspectionPanel, 'push');
            app.CollectbeadsforheatmapButton.ButtonPushedFcn = createCallbackFcn(app, @CollectbeadsforheatmapButtonPushed, true);
            app.CollectbeadsforheatmapButton.Text = 'Collect beads for heatmap';
            app.Bead_fit_parameter_table = uitable(app.ForcecalibrationTab);
            app.Bead_fit_parameter_table.ColumnName = {'Bead'; 'R_sq'; 'fo'; 'kp'; 'phip'; 'z_node'};
            app.Bead_fit_parameter_table.RowName = {};
            app.Bead_fit_parameter_table.ColumnSortable = true;
            app.BeadsnotsuitableforforcecalibrationLabel = uilabel(app.ForcecalibrationTab);
            app.BeadsnotsuitableforforcecalibrationLabel.Text = 'Beads not suitable for force calibration';
            app.FCBeadsTextArea = uitextarea(app.ForcecalibrationTab);
            app.HeatmapgenerationTab = uitab(app.TabGroup);
            app.HeatmapgenerationTab.Title = 'Heatmap generation';
            app.plot_bead_distribution = uiaxes(app.HeatmapgenerationTab);
            title(app.plot_bead_distribution, 'Bead distribution')
            xlabel(app.plot_bead_distribution, 'x (\mum)')
            ylabel(app.plot_bead_distribution, 'y (\mum)')
            zlabel(app.plot_bead_distribution, 'Z')
            app.plot_FC_factors = uiaxes(app.HeatmapgenerationTab);
            title(app.plot_FC_factors, 'FC Factor Histogram')
            xlabel(app.plot_FC_factors, 'FC Factors (pN/%)')
            ylabel(app.plot_FC_factors, 'Entries')
            zlabel(app.plot_FC_factors, 'Z')
            app.plot_full_heatmap = uiaxes(app.HeatmapgenerationTab);
            title(app.plot_full_heatmap, 'Complete Heatmap')
            xlabel(app.plot_full_heatmap, 'x (\mum)')
            ylabel(app.plot_full_heatmap, 'y (\mum)')
            zlabel(app.plot_full_heatmap, 'Z')
            app.NstructoperationsPanel = uipanel(app.HeatmapgenerationTab);
            app.NstructoperationsPanel.Title = 'N struct operations';
            app.LoadNstructButton = uibutton(app.NstructoperationsPanel, 'push');
            app.LoadNstructButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNstructButtonPushed, true);
            app.LoadNstructButton.Text = 'Load N struct';
            app.PlotparameterhistogramsButton = uibutton(app.NstructoperationsPanel, 'push');
            app.PlotparameterhistogramsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotparameterhistogramsButtonPushed, true);
            app.PlotparameterhistogramsButton.Text = 'Plot parameter histograms';
            app.RemoveparameteroutliersButton = uibutton(app.NstructoperationsPanel, 'push');
            app.RemoveparameteroutliersButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveparameteroutliersButtonPushed, true);
            app.RemoveparameteroutliersButton.Text = 'Remove parameter outliers';
            app.infiltertracestabLabel = uilabel(app.NstructoperationsPanel);
            app.infiltertracestabLabel.Text = '(in filter traces tab)';
            app.ExportNtoworkspaceButton = uibutton(app.NstructoperationsPanel, 'push');
            app.ExportNtoworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExportNtoworkspaceButtonPushed, true);
            app.ExportNtoworkspaceButton.Text = 'Export N to workspace';
            app.ClearNButton = uibutton(app.NstructoperationsPanel, 'push');
            app.ClearNButton.ButtonPushedFcn = createCallbackFcn(app, @ClearNButtonPushed, true);
            app.ClearNButton.Text = 'Clear N';
            app.HeatmapoperationsPanel = uipanel(app.HeatmapgenerationTab);
            app.HeatmapoperationsPanel.Title = 'Heatmap operations';
            app.CreateheatmapButton = uibutton(app.HeatmapoperationsPanel, 'push');
            app.CreateheatmapButton.ButtonPushedFcn = createCallbackFcn(app, @CreateheatmapButtonPushed, true);
            app.CreateheatmapButton.Text = 'Create heatmap';
            app.zforforceestimationumEditField = uieditfield(app.HeatmapoperationsPanel, 'numeric');
            app.zforforceestimationumEditField.Value = 1;
            app.MeshmergingsizeumEditField = uieditfield(app.HeatmapoperationsPanel, 'numeric');
            app.MeshmergingsizeumEditField.Value = 10;
            app.LoadHeatmapButton = uibutton(app.HeatmapoperationsPanel, 'push');
            app.LoadHeatmapButton.ButtonPushedFcn = createCallbackFcn(app, @LoadHeatmapButtonPushed, true);
            app.LoadHeatmapButton.Text = 'Load Heatmap';
            app.SaveHeatmapButton = uibutton(app.HeatmapoperationsPanel, 'push');
            app.SaveHeatmapButton.ButtonPushedFcn = createCallbackFcn(app, @SaveHeatmapButtonPushed, true);
            app.SaveHeatmapButton.Text = 'Save Heatmap';
            app.SamplepowerfitButton = uibutton(app.HeatmapoperationsPanel, 'push');
            app.SamplepowerfitButton.ButtonPushedFcn = createCallbackFcn(app, @SamplepowerfitButtonPushed, true);
            app.SamplepowerfitButton.Text = 'Sample power fit';
            app.zforforceestimationumLabel = uilabel(app.HeatmapoperationsPanel);
            app.zforforceestimationumLabel.Text = 'z for force estimation (um)';
            app.MeshmergingsizeumLabel = uilabel(app.HeatmapoperationsPanel);
            app.MeshmergingsizeumLabel.Text = 'Mesh merging size (um)';
            app.showbeadsCheckBox_heatmap = uicheckbox(app.HeatmapoperationsPanel);
            app.showbeadsCheckBox_heatmap.ValueChangedFcn = createCallbackFcn(app, @showbeadsCheckBox_heatmapValueChanged, true);
            app.showbeadsCheckBox_heatmap.Text = 'show beads?';
            app.showbeadsCheckBox_heatmap.Value = true;
            app.LoadingratefitparametersTextArea_2Label = uilabel(app.HeatmapgenerationTab);
            app.LoadingratefitparametersTextArea_2Label.Text = 'Force calibration factor fit paramters (normal distribution)';
            app.ForcecalibrationfactorfitparamtersnormaldistributionTextArea = uitextarea(app.HeatmapgenerationTab);
            app.NstructcontentPanel = uipanel(app.HeatmapgenerationTab);
            app.NstructcontentPanel.Title = 'N struct content';
            app.table_power_values = uitable(app.NstructcontentPanel);
            app.table_power_values.ColumnName = {'Power Value'; 'Number of Beads'};
            app.table_power_values.RowName = {};
            app.VisusalizebeadcollectionButton = uibutton(app.NstructcontentPanel, 'push');
            app.VisusalizebeadcollectionButton.ButtonPushedFcn = createCallbackFcn(app, @VisusalizebeadcollectionButtonPushed, true);
            app.VisusalizebeadcollectionButton.Text = 'Visusalize bead collection';
            app.PowerEditField = uieditfield(app.NstructcontentPanel, 'numeric');
            app.PowerEditField.Value = 1;
            app.PowerLabel = uilabel(app.NstructcontentPanel);
            app.PowerLabel.Text = 'Power';
            app.RuptureforcedeterminationTab = uitab(app.TabGroup);
            app.RuptureforcedeterminationTab.Title = 'Rupture force determination';
            app.plot_RF_histogram = uiaxes(app.RuptureforcedeterminationTab);
            title(app.plot_RF_histogram, 'Force histogram')
            xlabel(app.plot_RF_histogram, 'Rupture force (pN)')
            ylabel(app.plot_RF_histogram, 'Entries')
            zlabel(app.plot_RF_histogram, 'Z')
            app.plot_Rupture_bead = uiaxes(app.RuptureforcedeterminationTab);
            title(app.plot_Rupture_bead, 'Rupture bead plot')
            xlabel(app.plot_Rupture_bead, 'Time (s)')
            ylabel(app.plot_Rupture_bead, 'z (nm)')
            zlabel(app.plot_Rupture_bead, 'Z')
            app.plot_LR_histogram = uiaxes(app.RuptureforcedeterminationTab);
            title(app.plot_LR_histogram, 'Loading Rate histogram')
            xlabel(app.plot_LR_histogram, 'Loading rate (pN/s)')
            ylabel(app.plot_LR_histogram, 'Entries')
            zlabel(app.plot_LR_histogram, 'Z')
            app.plot_RF_fit = uiaxes(app.RuptureforcedeterminationTab);
            title(app.plot_RF_fit, 'Force histogram fit')
            xlabel(app.plot_RF_fit, 'Rupture force (pN)')
            ylabel(app.plot_RF_fit, 'Entries')
            zlabel(app.plot_RF_fit, 'Z')
            app.plot_LR_fit = uiaxes(app.RuptureforcedeterminationTab);
            title(app.plot_LR_fit, 'Loading rate histogram fit')
            xlabel(app.plot_LR_fit, 'Loading rate (pN/s)')
            ylabel(app.plot_LR_fit, 'Entries')
            zlabel(app.plot_LR_fit, 'Z')
            app.LoadingratefitparametersLabel = uilabel(app.RuptureforcedeterminationTab);
            app.LoadingratefitparametersLabel.Text = 'Loading rate fit parameters';
            app.ForcefitparametersTextAreaLabel = uilabel(app.RuptureforcedeterminationTab);
            app.ForcefitparametersTextAreaLabel.Text = 'Force fit parameters';
            app.LoadingratefitparametersTextArea = uitextarea(app.RuptureforcedeterminationTab);
            app.ForcefitparametersTextArea = uitextarea(app.RuptureforcedeterminationTab);
            app.RuptureforcesettingsPanel = uipanel(app.RuptureforcedeterminationTab);
            app.RuptureforcesettingsPanel.Title = 'Rupture force settings';
            app.RFt_minsEditField = uieditfield(app.RuptureforcesettingsPanel, 'numeric');
            app.RFt_minsEditField.Tooltip = {'Only approximate time range needed.'};
            app.RFt_minsEditField.Value = 55;
            app.RFt_maxsEditField = uieditfield(app.RuptureforcesettingsPanel, 'numeric');
            app.RFt_maxsEditField.Tooltip = {'Only approximate time range needed.'};
            app.RFt_maxsEditField.Value = 150;
            app.field_z_averaging_window_RF = uieditfield(app.RuptureforcesettingsPanel, 'numeric');
            app.field_z_averaging_window_RF.Value = 120;
            app.field_z_cutoff_RF = uieditfield(app.RuptureforcesettingsPanel, 'numeric');
            app.field_z_cutoff_RF.Value = 2000;
            app.RuptureforcestartsLabel = uilabel(app.RuptureforcesettingsPanel);
            app.RuptureforcestartsLabel.Text = 'Rupture force start (s)';
            app.RuptureforceendsLabel = uilabel(app.RuptureforcesettingsPanel);
            app.RuptureforceendsLabel.Text = 'Rupture force end (s)';
            app.AveragingwindowforzLabel_2 = uilabel(app.RuptureforcesettingsPanel);
            app.AveragingwindowforzLabel_2.Text = 'Averaging window for z (-)';
            app.CutoffforznmLabel = uilabel(app.RuptureforcesettingsPanel);
            app.CutoffforznmLabel.Text = 'Cut-off for z (nm)';
            app.FindruptureforceButton = uibutton(app.RuptureforcesettingsPanel, 'push');
            app.FindruptureforceButton.ButtonPushedFcn = createCallbackFcn(app, @FindruptureforceButtonPushed, true);
            app.FindruptureforceButton.Text = 'Find rupture force';
            app.ManualruptureforcedeterminationPanel = uipanel(app.RuptureforcedeterminationTab);
            app.ManualruptureforcedeterminationPanel.Title = 'Manual rupture force determination';
            app.PlotrupturebeadtraceButton = uibutton(app.ManualruptureforcedeterminationPanel, 'push');
            app.PlotrupturebeadtraceButton.ButtonPushedFcn = createCallbackFcn(app, @PlotrupturebeadtraceButtonPushed, true);
            app.PlotrupturebeadtraceButton.Text = 'Plot rupture-bead trace';
            app.suggestedRupturetimeTextArea = uitextarea(app.ManualruptureforcedeterminationPanel);
            app.field_rupture_time_input = uieditfield(app.ManualruptureforcedeterminationPanel, 'numeric');
            app.field_rupture_time_input.Tooltip = {'If "-1" remains, then the suggested rupture time above will be assiged to the selected bead'};
            app.field_rupture_time_input.Value = -1;
            app.SuggestedrupturetimesLabel = uilabel(app.ManualruptureforcedeterminationPanel);
            app.SuggestedrupturetimesLabel.Text = 'Suggested rupture time (s)';
            app.RupturetimeinputsLabel = uilabel(app.ManualruptureforcedeterminationPanel);
            app.RupturetimeinputsLabel.Text = 'Rupture time input (s)';
            app.AssignrupturetimeButton = uibutton(app.ManualruptureforcedeterminationPanel, 'push');
            app.AssignrupturetimeButton.ButtonPushedFcn = createCallbackFcn(app, @AssignrupturetimeButtonPushed, true);
            app.AssignrupturetimeButton.Text = 'Assign rupture time';
            app.BeadsformanualRFcheckTextArea = uitextarea(app.ManualruptureforcedeterminationPanel);
            app.TracesformanualruptureforcedeterminationLabel = uilabel(app.ManualruptureforcedeterminationPanel);
            app.TracesformanualruptureforcedeterminationLabel.Text = 'Traces for manual rupture force determination';
            app.RuptureforcehistogramsPanel = uipanel(app.RuptureforcedeterminationTab);
            app.RuptureforcehistogramsPanel.Title = 'Rupture force histograms';
            app.PlotruptureforceandloadingratehistogramButton = uibutton(app.RuptureforcehistogramsPanel, 'push');
            app.PlotruptureforceandloadingratehistogramButton.ButtonPushedFcn = createCallbackFcn(app, @PlotruptureforceandloadingratehistogramButtonPushed, true);
            app.PlotruptureforceandloadingratehistogramButton.Text = 'Assign rupture force and plot rupture force and loading rate histogram';
            app.LoadingratefitLabel = uilabel(app.RuptureforcehistogramsPanel);
            app.LoadingratefitLabel.Text = 'Loading rate fit';
            app.LoadingratefitDropDown = uidropdown(app.RuptureforcehistogramsPanel);
            app.LoadingratefitDropDown.Items = {'normal', 'lognormal'};
            app.LoadingratefitDropDown.Value = 'normal';
            app.ForcefitDropDownLabel = uilabel(app.RuptureforcehistogramsPanel);
            app.ForcefitDropDownLabel.Text = 'Force fit';
            app.ForcefitDropDown = uidropdown(app.RuptureforcehistogramsPanel);
            app.ForcefitDropDown.Items = {'normal', 'lognormal'};
            app.ForcefitDropDown.Value = 'normal';
            app.FitloadingrateandruptureforceButton = uibutton(app.RuptureforcehistogramsPanel, 'push');
            app.FitloadingrateandruptureforceButton.ButtonPushedFcn = createCallbackFcn(app, @FitloadingrateandruptureforceButtonPushed, true);
            app.FitloadingrateandruptureforceButton.Text = 'Fit loading rate and rupture force';
            app.DataexportcsvPanel = uipanel(app.RuptureforcedeterminationTab);
            app.DataexportcsvPanel.Title = 'Data export (*.csv)';
            app.field_RF_data_filename_export = uieditfield(app.DataexportcsvPanel, 'text');
            app.field_RF_data_filename_export.Value = 'CBM3a_10pNs_1';
            app.FilenameLabel = uilabel(app.DataexportcsvPanel);
            app.FilenameLabel.Text = 'File name';
            app.FilepathLabel = uilabel(app.DataexportcsvPanel);
            app.FilepathLabel.Text = 'File path';
            app.field_RF_data_filepath_export = uitextarea(app.DataexportcsvPanel);
            app.field_RF_data_filepath_export.Value = {'D:\Nextcloud\AFS\DATA'};
            app.ExportruptureforcedataButton = uibutton(app.DataexportcsvPanel, 'push');
            app.ExportruptureforcedataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportruptureforcedataButtonPushed, true);
            app.ExportruptureforcedataButton.Text = 'Export rupture force data';
            app.AutomatedstokesforcecalibrationTab = uitab(app.TabGroup);
            app.AutomatedstokesforcecalibrationTab.Title = 'Automated stokes force calibration';
            app.FilenameforNstructEditFieldLabel = uilabel(app.AutomatedstokesforcecalibrationTab);
            app.FilenameforNstructEditFieldLabel.Text = 'File name for N struct';
            app.FilenameforNstructEditField = uieditfield(app.AutomatedstokesforcecalibrationTab, 'text');
            app.FilenameforNstructEditField.Value = '20230301_N_chip41';
            app.SelectfilepathandrunautomatedforcecalibrationButton = uibutton(app.AutomatedstokesforcecalibrationTab, 'push');
            app.SelectfilepathandrunautomatedforcecalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfilepathandrunautomatedforcecalibrationButtonPushed, true);
            app.SelectfilepathandrunautomatedforcecalibrationButton.Text = 'Select file path and run automated force calibration';
            app.FilePathTextAreaLabel = uilabel(app.AutomatedstokesforcecalibrationTab);
            app.FilePathTextAreaLabel.Text = 'File Path';
            app.display_filepath_automatedSFC = uitextarea(app.AutomatedstokesforcecalibrationTab);
            app.StatusTextAreaLabel = uilabel(app.AutomatedstokesforcecalibrationTab);
            app.StatusTextAreaLabel.Text = 'Status';
            app.display_status_automatedSFC = uitextarea(app.AutomatedstokesforcecalibrationTab);
            app.TipsInstructionsPanel = uipanel(app.AutomatedstokesforcecalibrationTab);
            app.TipsInstructionsPanel.Title = 'Tips & Instructions';
            app.Loadinsampledatasetanddefineanchorpointtimerange23Label = uilabel(app.TipsInstructionsPanel);
            app.Loadinsampledatasetanddefineanchorpointtimerange23Label.Text = {'1) Load in sample data set and define anchor point time range.'; '2) Define the standard deviation in z cut-off in the filter traces tab.'; '3) Define time range for SFC, z cut-off and starting parameters.'; '4) Execute force fit and inspect fit parameter histograms.'; '5) Repeat force fit with different starting parameters.'; '6) Verify that different starting parameters result in the same final fit parameters'; '7) Change starting paramters to the final fit paramters.'; '8) Execute automated force fit.'; '9) The automated force fit can be stopped by pressing "Ctrl+C".'};
            app.GeneralTheautomatedforcecalibrationonlyworksifalldatasetsLabel = uilabel(app.TipsInstructionsPanel);
            app.GeneralTheautomatedforcecalibrationonlyworksifalldatasetsLabel.Text = {'General remarks: '; ''; 'The automated force calibration only works if all data sets have the same trace style. This means that the time range for anchor point and force calibration should be identical for all data sets within the same folder. '; ''; 'The force fit is sensitive to the starting parameters and it is advised to test the force fit for a sample data set for various starting parameters to see if they end up at the same final values. Use those final values as starting parameters for the automated force fit. '; ''; 'After executing the automated SFC, the N struct will be loaded in the app as well and the heatmap can be generated right away.'};
            app.RemovebeadsButton = uibutton(app.UIFigure, 'push');
            app.RemovebeadsButton.ButtonPushedFcn = createCallbackFcn(app, @RemovebeadsButtonPushed, true);
            app.RemovebeadsButton.Text = 'Remove bead(s)';
            app.ExporttrackingdataMtoworkspaceButton = uibutton(app.UIFigure, 'push');
            app.ExporttrackingdataMtoworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttrackingdataMtoworkspaceButtonPushed, true);
            app.ExporttrackingdataMtoworkspaceButton.Text = 'Export tracking data (M) to workspace';
            app.SystempropertiesPanel = uipanel(app.UIFigure);
            app.SystempropertiesPanel.Title = 'System properties';
            app.MagnificationDropDownLabel = uilabel(app.SystempropertiesPanel);
            app.MagnificationDropDownLabel.Text = 'Magnification';
            app.MagnificationDropDown = uidropdown(app.SystempropertiesPanel);
            app.MagnificationDropDown.Items = {'4x', '10x', '20x', '40x'};
            app.MagnificationDropDown.Value = '10x';
            app.field_bead_diameter = uieditfield(app.SystempropertiesPanel, 'numeric');
            app.field_bead_diameter.Value = 5.14;
            app.BeaddiameterumLabel = uilabel(app.SystempropertiesPanel);
            app.BeaddiameterumLabel.Text = 'Bead diameter (um)';
            app.field_general_status_update = uitextarea(app.UIFigure);
            app.StatusupdateLabel = uilabel(app.UIFigure);
            app.StatusupdateLabel.Text = 'Status update';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AFS_GUI_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end